classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RecommendationTextArea  matlab.ui.control.TextArea
        ResetButton             matlab.ui.control.Button
        TemperatureLabel        matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
        StatusLampLabel         matlab.ui.control.Label
        HumidityLabel           matlab.ui.control.Label
        LightLabel              matlab.ui.control.Label
        RecommendationButton    matlab.ui.control.Button
        AnalyzeButton           matlab.ui.control.Button
        ImportButton            matlab.ui.control.Button
        AlertTextArea           matlab.ui.control.TextArea
        CropStatusLabel         matlab.ui.control.Label
        MoistureLabel           matlab.ui.control.Label
        RainfallLabel           matlab.ui.control.Label
        DataTable               matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       Data = table()
       DataLoaded = false
    end


        methods (Access = private)

            function updateDisplay(app)
                if isempty(app.Data) || height(app.Data)==0
                    return;
                end
                i = height(app.Data);
                app.TemperatureLabel.Text = sprintf('%.1f °C', app.Data.Temperature(i));
                app.MoistureLabel.Text = sprintf('%.1f %%', app.Data.SoilMoisture(i));
                app.HumidityLabel.Text = sprintf('%.1f %%', app.Data.Humidity(i));
                app.RainfallLabel.Text = sprintf('%.1f mm', app.Data.Rainfall(i));
                app.LightLabel.Text = sprintf('%.0f', app.Data.LightIntensity(i));
            end
        end


             methods (Access = private)

                 function plotData(app)
                     if isempty(app.Data) || height(app.Data)==0
                         return;
                     end
                     cla(app.UIAxes);
                     hold(app.UIAxes,'on');
                     x = 1:height(app.Data);
                     plot(app.UIAxes,x,app.Data.Temperature,'LineWidth',1.4);
                     plot(app.UIAxes,x,app.Data.SoilMoisture,'LineWidth',1.4);
                     plot(app.UIAxes,x,app.Data.Humidity,'LineWidth',1.4);
                     plot(app.UIAxes,x,app.Data.Rainfall,'LineWidth',1.4);
                     plot(app.UIAxes,x,app.Data.LightIntensity/10,'LineWidth',1.4);
                     hold(app.UIAxes,'off');
                     title(app.UIAxes,'Agricultural Sensor Data Over Time');
                     xlabel(app.UIAxes,'Observation');
                     ylabel(app.UIAxes,'Value');
                     legend(app.UIAxes,{'Temperature','Soil Moisture','Humidity', ...
                         'Rainfall','Light Intensity / 10'},'Location','best');
                     grid(app.UIAxes,'on');
                 end

             end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile( ...
                {'*.csv','CSV Files (*.csv)'; ...
                '*.xlsx','Excel Files (*.xlsx)'}, ...
                'Select Agricultural Dataset');

            if isequal(file,0)
                app.AlertTextArea.Value = {'Dataset selection cancelled.'};
                return;
            end

            try
                app.Data = readtable(fullfile(path,file));
                app.DataTable.Data = app.Data;
                app.DataLoaded = true;
                app.AlertTextArea.Value = ...
                    {sprintf('Dataset loaded successfully: %d rows.', height(app.Data))};

                app.updateDisplay();
                app.plotData();
            catch ME
                app.AlertTextArea.Value = {['Error loading dataset: ' ME.message]};
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
        
    if ~app.DataLoaded || isempty(app.Data)
        app.AlertTextArea.Value = {'Please import a dataset first.'};
        return;
    end

    i = height(app.Data);
    temp = app.Data.Temperature(i);
    moisture = app.Data.SoilMoisture(i);
    humidity = app.Data.Humidity(i);

    issues = {};
    status = 'Normal';

    % Example thresholds - adjust for your crop
    if moisture < 30
        status = 'Critical';
        issues{end+1} = 'Soil moisture critically low';
    elseif moisture < 45
        status = 'Warning';
        issues{end+1} = 'Soil moisture below optimal range';
    end

    if temp > 35
        status = 'Critical';
        issues{end+1} = 'Temperature critically high';
    elseif temp > 30 && ~strcmp(status,'Critical')
        status = 'Warning';
        issues{end+1} = 'Temperature above optimal range';
    end

    if humidity < 40 && ~strcmp(status,'Critical')
        status = 'Warning';
        issues{end+1} = 'Humidity low';
    end

    % Update crop status label + lamp color
    app.CropStatusLabel.Text = status;
    switch status
        case 'Normal'
            app.StatusLamp.Color = [0 1 0];   % green
        case 'Warning'
            app.StatusLamp.Color = [1 0.65 0]; % amber
        case 'Critical'
            app.StatusLamp.Color = [1 0 0];   % red
    end

    % Alert
    if isempty(issues)
        app.AlertTextArea.Value = {'All conditions normal.'};
    else
        app.AlertTextArea.Value = issues;
    end
          
        end

        % Button pushed function: RecommendationButton
        function RecommendationButtonPushed(app, event)
           
                if ~app.DataLoaded
                    app.RecommendationButton.Value = {'Import and analyze data first.'};
                    return;
                end

                status = app.CropStatusLabel.Text;
                switch status
                    case 'Critical'
                        app.RecommendationTextArea.Value = {'Immediate irrigation and shade recommended.'};
                    case 'Warning'
                
                       app.RecommendationTextArea.Value = {'Monitor closely; consider irrigation within 24 hours.'};
                    otherwise
                    app.RecommendationTextArea.Value = {'Conditions are favorable. No action needed.'};
                end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Data = table();
            app.DataLoaded = false;

            app.DataTable.Data = table();
            cla(app.UIAxes);

            app.TemperatureLabel.Text = '--';
            app.MoistureLabel.Text = '--';
            app.HumidityLabel.Text = '--';
            app.RainfallLabel.Text = '--';
            app.LightLabel.Text = '--';

            app.CropStatusLabel.Text = 'CropStatus';
            app.StatusLamp.Color = [0 1 0];

            app.AlertTextArea.Value = {'Please import a dataset to begin.'};
            app.RecommendationTextArea.Value = {'No recommendation available.'};  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataTable.RowName = {};
            app.RainfallLabel = uilabel(app.UIFigure);
            app.RainfallLabel.Text = 'Rainfall';
            app.MoistureLabel = uilabel(app.UIFigure);
            app.MoistureLabel.Text = 'Moisture';
            app.CropStatusLabel = uilabel(app.UIFigure);
            app.CropStatusLabel.Text = 'CropStatus';
            app.AlertTextArea = uitextarea(app.UIFigure);
            app.AlertTextArea.Value = {'Alert Text Area'};
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import button';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze button';
            app.RecommendationButton = uibutton(app.UIFigure, 'push');
            app.RecommendationButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendationButtonPushed, true);
            app.RecommendationButton.Text = 'Reccomendation Button';
            app.LightLabel = uilabel(app.UIFigure);
            app.LightLabel.Text = 'LightLabel';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status Lamp';
            app.StatusLamp = uilamp(app.UIFigure);
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'ResetButton';
            app.RecommendationTextArea = uitextarea(app.UIFigure);
            app.RecommendationTextArea.Value = {'RecommendationTextArea'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end